classdef sec1gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Entert_maxEditField             matlab.ui.control.NumericEditField
        Entert_maxEditFieldLabel        matlab.ui.control.Label
        SecondDerivativeatt0isEditField  matlab.ui.control.NumericEditField
        SecondDerivativeatt0isEditFieldLabel  matlab.ui.control.Label
        Firstderivativeatt0isEditField  matlab.ui.control.NumericEditField
        Firstderivativeatt0isEditFieldLabel  matlab.ui.control.Label
        X3EditField                     matlab.ui.control.NumericEditField
        X3EditFieldLabel                matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField_2                    matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        BackButton                      matlab.ui.control.Button
        SingleRandomVariableLabel       matlab.ui.control.Label
        LoadFileandAnalyzeButton        matlab.ui.control.Button
        UIAxes3_3                       matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        RV
        tmax
    end
    
    methods (Access = private)

    end
    
    methods (Access = public)
        
    function rvType = RVType(~, RV)
        uniqueValues = unique(RV);
        ratioUnique = length(uniqueValues) / length(RV);
        threshold = 0.05;
        if ratioUnique > threshold  
            rvType = 'Continuous';
        else 
            rvType = 'Discrete';
        end
            fprintf('Ratio of unique values: %.4f\n', ratioUnique);
            fprintf('Determined RV type: %s\n', rvType);
    end

    function [PDF, x] = ksDen(~,data, bandwidth)
        min_x = min(data) - 3 * bandwidth;
        max_x = max(data) + 3 * bandwidth;
        x = linspace(min_x, max_x, ceil(sqrt(length(data))));
        PDF = zeros(size(x));
        n = length(data);
        for i = 1:length(x)
            for j = 1:n
                PDF(i) = PDF(i) + (1 / sqrt(2 * pi)) * exp(-0.5 * ((x(i) - data(j)) / bandwidth).^2);
            end
            PDF(i) = PDF(i) / (n * bandwidth);
        end
    end

    function K = gaussianKernel(~,x)
        K = (1 / sqrt(2 * pi)) * exp(-0.5 * x.^2);
    end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            delete(app.UIFigure);
            mainGUI;
            
        end

        % Button pushed function: LoadFileandAnalyzeButton
        function LoadFileandAnalyzeButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Select a .mat File to Load');
    
    % Check if the user selected a file or canceled the dialog
    if isequal(file, 0)
        % User canceled the file selection
        uialert(app.UIFigure, 'No file selected.', 'File Selection');
    else
        % Construct the full file path
        fullFilePath = fullfile(path, file);
        
        % Display the selected file path (optional, for debugging)
        disp(['Selected file: ', fullFilePath]);
        
        % Load the .mat file
        try
            % Get variable names in the .mat file
            variables = who('-file', fullFilePath);
            
            % Ensure the file contains exactly one variable
            if numel(variables) == 1
                % Load the single variable
                matData = load(fullFilePath, variables{1});
                
                % Access the variable dynamically
                data = matData.(variables{1});
                
                % Check if the variable is a 1-row matrix
                if isrow(data)
                    % Assign it directly to RV
                    app.RV = data;
                    
                    % Display loaded data (optional, for debugging)
                    disp(['Loaded variable: ', variables{1}]);
                    disp('RV:');
                    disp(app.RV);
                else
                    % Alert if the variable is not a 1-row matrix
                    uialert(app.UIFigure, 'The variable must be a 1-row matrix.', 'Error');
                end
            else
                % Alert if the file does not contain exactly one variable
                uialert(app.UIFigure, 'The file must contain exactly one variable.', 'Error');
            end
        catch ME
            % Handle any errors during file loading
            uialert(app.UIFigure, ['Error loading file: ', ME.message], 'Error');
        end
    end
            app.tmax=app.Entert_maxEditField.Value;
            % Calculate mean and variance

            rvType = app.RVType(app.RV);
            n = length(app.RV);
            numBins = ceil(sqrt(n));

        if strcmpi(rvType, 'Discrete')
            % PDF/PMF and CDF Estimation
            [counts, edges] = histcounts(app.RV, numBins, 'Normalization','probability');
            xValues = (edges(1:end-1) + edges(2:end)) / 2; 
    
            % Plot PDF
            bar(app.UIAxes,xValues, counts);

            % Calculate and plot CDF
            cdfValues = cumsum(counts) * (edges(2) - edges(1));
            stairs(app.UIAxes2,edges(1:end-1), cdfValues);
            grid on;

            % MGF, First Moment, and Second Moment Calculation
            t = linspace(0, app.tmax, 1000); 
            mgf = exp(t' * xValues) * counts'; 
            firstMoment = exp(t' * xValues) * (xValues .* counts)';
            secondMoment = exp(t' * xValues) * (xValues.^2 .* counts)';
    
            % Calculate first and second moment at t = 0
            firstMoment_at_0 = double(sum(counts .* xValues));  % E[X]
            secondMoment_at_0 = double(sum(counts .* (xValues.^2)));  % E[X^2]
    
            % Plot MGF, Firs-t Moment, and Second Moment in subplots

            plot(app.UIAxes3,t, mgf, 'k');

            plot(app.UIAxes3_2,t, firstMoment, 'r');

            plot(app.UIAxes3_3,t, secondMoment, 'b');

            meanRV = mean(app.RV);
            varianceRV = var(app.RV);
            %thirdMoment = mean((app.RV-meanRV).^3);  % Calculate 3rd central moment
            %thirdMoment = exp(t' * xValues) * (xValues.^3 .* counts)';
            app.X3EditField.Value =  mean((app.RV-meanRV).^3);  % Calculate 3rd central moment
            %app.X3EditField.Value =  double(exp(t .* xValues) .* (xValues.^3 .* counts));  % Calculate 3rd central moment
    
    elseif strcmpi(rvType, 'Continuous')
            bandwidth = 0.1;
            [PDF, x] = app.ksDen(app.RV, bandwidth);
    
            % Plot the PDF

            plot(app.UIAxes,x, PDF);
    
            % Calculate CDF using trapezoidal integration
            CDF = cumtrapz(x, PDF);
    
            % Plot CDF
            plot(app.UIAxes2,x, CDF);
    
            % Calculate MGF, First Moment, and Second Moment
            t = linspace(0, app.tmax, 1000);
            mgf = zeros(size(t));
            firstMoment = zeros(size(t));
            secondMoment = zeros(size(t));
    
            for i = 1:length(t)
                mgf(i) = trapz(x, exp(t(i) * x) .* PDF);
                firstMoment(i) = trapz(x, x .* exp(t(i) * x) .* PDF);
                secondMoment(i) = trapz(x, (x.^2) .* exp(t(i) * x) .* PDF);
            end
    
            % Calculate first and second moment at t = 0
            firstMoment_at_0 = double(trapz(x, x .* PDF));
            secondMoment_at_0 = double(trapz(x, (x.^2) .* PDF));
    
            % Plot MGF, First Moment, and Second Moment

            plot(app.UIAxes3,t, mgf, 'k');

            plot(app.UIAxes3_2,t, firstMoment, 'r');

            plot(app.UIAxes3_3,t, secondMoment, 'b');

            meanRV = mean(app.RV);
            varianceRV = var(app.RV);
            %thirdMoment = double(mean((app.RV-meanRV).^3));
            app.X3EditField.Value = mean((app.RV-meanRV).^3);
            %app.X3EditField.Value = mean((app.RV).^3);

        end
            app.XEditField_2.Value=meanRV;
            app.XEditField.Value=varianceRV;
            app.Firstderivativeatt0isEditField.Value=firstMoment_at_0;
            app.SecondDerivativeatt0isEditField.Value=secondMoment_at_0;

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
           
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
           
        end

        % Button down function: UIAxes3_2
        function UIAxes3_2ButtonDown(app, event)

        end

        % Button down function: UIAxes3_3
        function UIAxes3_3ButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Probability Distribution of X')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, '{f_x}(x)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Cumulative Distribution of X')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, '{F_X}(x)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Moment Generating Function of X')
            xlabel(app.UIAxes3, 't')
            ylabel(app.UIAxes3, '{M_X}(t)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'First Derivative of {M_X}(t)')
            xlabel(app.UIAxes3_2, 't')
            ylabel(app.UIAxes3_2, '{d/dt} {M_X}(t)')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes3_2ButtonDown, true);
            app.UIAxes3_3 = uiaxes(app.UIFigure);
            title(app.UIAxes3_3, 'Second Derivative of {M_X}(t)')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, '{d^2}/dt^2 {M_X}(t)')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes3_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3_3ButtonDown, true);
            app.LoadFileandAnalyzeButton = uibutton(app.UIFigure, 'push');
            app.LoadFileandAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileandAnalyzeButtonPushed, true);
            app.LoadFileandAnalyzeButton.Text = 'Load File and Analyze';
            app.SingleRandomVariableLabel = uilabel(app.UIFigure);
            app.SingleRandomVariableLabel.Text = 'Single Random Variable';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Interpreter = 'tex';
            app.XEditField_2Label.Text = 'μ_X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Interpreter = 'tex';
            app.XEditFieldLabel.Text = 'σ^2_X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.X3EditFieldLabel = uilabel(app.UIFigure);
            app.X3EditFieldLabel.Interpreter = 'tex';
            app.X3EditFieldLabel.Text = 'Ɛ[{X^3}]';
            app.X3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Firstderivativeatt0isEditFieldLabel = uilabel(app.UIFigure);
            app.Firstderivativeatt0isEditFieldLabel.Text = 'First derivative at t = 0 is';
            app.Firstderivativeatt0isEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondDerivativeatt0isEditFieldLabel = uilabel(app.UIFigure);
            app.SecondDerivativeatt0isEditFieldLabel.Text = 'Second Derivative at t = 0 is';
            app.SecondDerivativeatt0isEditField = uieditfield(app.UIFigure, 'numeric');
            app.Entert_maxEditFieldLabel = uilabel(app.UIFigure);
            app.Entert_maxEditFieldLabel.Interpreter = 'tex';
            app.Entert_maxEditFieldLabel.Text = 'Enter t_max';
            app.Entert_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.Entert_maxEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sec1gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end