classdef sec2gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        YEditField_2               matlab.ui.control.NumericEditField
        YEditField_2Label          matlab.ui.control.Label
        COV_XYEditField            matlab.ui.control.NumericEditField
        COV_XYEditFieldLabel       matlab.ui.control.Label
        XYEditField                matlab.ui.control.NumericEditField
        XYEditFieldLabel           matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField_2               matlab.ui.control.NumericEditField
        XLabel                     matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
        BackButton                 matlab.ui.control.Button
        JointRandomVariablesLabel  matlab.ui.control.Label
        LoadFileandAnalyzeButton   matlab.ui.control.Button
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        X
        Y
    end
    
    methods (Access = public)
        
%%
function rvType = RVType(~,RV)
    uniqueValues = unique(RV);
    ratioUnique = length(uniqueValues) / length(RV);
    threshold = 0.05;  % Adjust threshold as needed
    if ratioUnique > threshold
        rvType = 'Continuous';
    else
        rvType = 'Discrete';
    end
    fprintf('Ratio of unique values for %s: %.4f\n', inputname(1), ratioUnique);
    fprintf('Determined RV type: %s\n', rvType);

end

function [pdf, x] = ksDen(~,data, bandwidth)
    min_x = min(data) - 3 * bandwidth;
    max_x = max(data) + 3 * bandwidth;
    x_points = ceil(sqrt(length(data)));  % Number of points for evaluation
    x = linspace(min_x, max_x, x_points);
    pdf = zeros(size(x));
    n = length(data);
    for i = 1:length(x)
        for j = 1:n
            pdf(i) = pdf(i) + ((1 / sqrt(2 * pi)) * exp(-0.5 * (x(i) - data(j)).^2) / bandwidth);
        end
        pdf(i) = pdf(i) / (n * bandwidth);
    end
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileandAnalyzeButton
        function LoadFileandAnalyzeButtonPushed(app, event)
   % Open a file dialog to select a .mat file
    [file, path] = uigetfile('*.mat', 'Select a .mat File to Load');
    
    % Check if the user selected a file or canceled the dialog
    if isequal(file, 0)
        % User canceled the file selection
        uialert(app.UIFigure, 'No file selected.', 'File Selection');
    else
        % Construct the full file path
        fullFilePath = fullfile(path, file);
        
        % Display the selected file path (optional, for debugging)
        disp(['Selected file: ', fullFilePath]);
        
        % Load the .mat file
        try
            % Get variable names in the .mat file
            variables = who('-file', fullFilePath);
            
            % Ensure the file contains at least one variable
            if ~isempty(variables)
                % Load the first variable
                matData = load(fullFilePath, variables{1});
                
                % Access the variable dynamically
                data = matData.(variables{1});
                
                % Check if the variable is a 2-row matrix
                if ismatrix(data) && size(data, 1) == 2
                    % Split the rows into RV1 and RV2
                    app.X = data(1, :);
                    app.Y = data(2, :);
                    
                    % Display loaded variables (optional, for debugging)
                    disp(['Loaded variable: ', variables{1}]);
                    disp('X:');
                    disp(app.X);
                    disp('Y:');
                    disp(app.Y);
                else
                    % Alert if the variable is not a 2-row matrix
                    uialert(app.UIFigure, 'The variable must be a 2-row matrix.', 'Error');
                end
            else
                % Alert if no variables are found
                uialert(app.UIFigure, 'The selected file contains no variables.', 'Error');
            end
        catch ME
            % Handle any errors during file loading
            uialert(app.UIFigure, ['Error loading file: ', ME.message], 'Error');
        end
    end
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%
% Calculate histogram bin edges using Scott's rule
[~, bins_X] = histcounts(app.X, 100,'Normalization', 'probability');
[~, bins_Y] = histcounts(app.Y, 100,'Normalization', 'probability');

% Calculate 2D histogram
[H, xedges, yedges] = histcounts2(app.X, app.Y, bins_X, bins_Y, 'Normalization', 'probability');

% Create meshgrid for plotting (using bin centers)
xcenters = (xedges(1:end-1) + xedges(2:end)) / 2;
ycenters = (yedges(1:end-1) + yedges(2:end)) / 2;
[xpos, ypos] = meshgrid(xcenters, ycenters);

% Plot using surf
surf(app.UIAxes3,xpos, ypos, H');  % Use H directly, no need to flatten or filter
view(3); % Set 3D view

%% Marginal PDF Calculation
% Check RV type for X
rvTypeX = app.RVType(app.X);

if strcmp(rvTypeX, 'Discrete')
    % Estimate marginal PDF of X using histogram
    unique_x = unique(app.X);
    if length(unique_x) > 1
        [countsX, centersX] = hist(app.X, unique_x); % Use unique values as centers
        marginal_pdf_X = countsX / length(app.X);

        bar(app.UIAxes3,centersX, marginal_pdf_X);

    else
        warning('Cannot calculate marginal PDF of X using histogram. Too few unique values.');
    end

elseif strcmp(rvTypeX, 'Continuous')
    % Estimate marginal PDF of X using ksDen
    n_x = length(app.X);
    bandwidth_x = std(app.X) * n_x^(-1/5); % Example bandwidth
    [marginal_pdf_X, centersX] = app.ksDen(app.X, bandwidth_x);

    plot(app.UIAxes,centersX, marginal_pdf_X);

end

% Check RV type for Y
rvTypeY = app.RVType(app.Y);

if strcmp(rvTypeY, 'Discrete')
    % Estimate marginal PDF of Y using histogram
    unique_y = unique(app.Y);
    if length(unique_y) > 1
        [countsY, centersY] = hist(app.Y, unique_y); % Use unique values as centers
        marginal_pdf_Y = countsY / length(app.Y);

        stem(app.UIAxes2,centersY, marginal_pdf_Y);

    else
        warning('Cannot calculate marginal PDF of Y using histogram. Too few unique values.');
    end

elseif strcmp(rvTypeY, 'Continuous')
    % Estimate marginal PDF of Y using ksDen
    n_y = length(app.Y);
    bandwidth_y = std(app.Y) * n_y^(-1/5); % Example bandwidth
    [marginal_pdf_Y, centersY] = app.ksDen(app.Y, bandwidth_y);

    figure;
    plot(app.UIAxes2,centersY, marginal_pdf_Y);
end
meanX=mean(app.X);
meanY=mean(app.Y);
varX=var(app.X);
varY=var(app.Y); 

app.XEditField.Value = meanX;
app.YEditField.Value = meanY;
app.XEditField_2.Value = varX;
app.YEditField_2.Value = varY;

covarianceMatrix = cov(app.X, app.Y); % Covariance matrix
covXY = covarianceMatrix(1, 2);      % Covariance between X and Y
app.COV_XYEditField.Value=covXY;

correlationMatrix = corrcoef(app.X, app.Y); % Correlation coefficient matrix
corrXY = correlationMatrix(1, 2);          % Correlation coefficient between X and Y
app.XYEditField.Value=corrXY;


        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            delete(app.UIFigure);
            mainGUI;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Probability Distribution of X')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'f_X(x)')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Probability Distribution of Y')
            xlabel(app.UIAxes2, 'Y')
            ylabel(app.UIAxes2, 'f_Y(y)')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Joint PDFof X,Y')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, '{f_XY}(x,y)')
            app.LoadFileandAnalyzeButton = uibutton(app.UIFigure, 'push');
            app.LoadFileandAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileandAnalyzeButtonPushed, true);
            app.LoadFileandAnalyzeButton.Text = 'Load File and Analyze';
            app.JointRandomVariablesLabel = uilabel(app.UIFigure);
            app.JointRandomVariablesLabel.Text = 'Joint Random Variables';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Interpreter = 'tex';
            app.XEditFieldLabel.Text = 'μ_X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Interpreter = 'tex';
            app.XLabel.Text = '{σ^2}_X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Interpreter = 'tex';
            app.YEditFieldLabel.Text = 'μ_Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.XYEditFieldLabel = uilabel(app.UIFigure);
            app.XYEditFieldLabel.Interpreter = 'tex';
            app.XYEditFieldLabel.Text = 'ρ_XY';
            app.XYEditField = uieditfield(app.UIFigure, 'numeric');
            app.COV_XYEditFieldLabel = uilabel(app.UIFigure);
            app.COV_XYEditFieldLabel.Interpreter = 'tex';
            app.COV_XYEditFieldLabel.Text = 'COV_XY';
            app.COV_XYEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Interpreter = 'tex';
            app.YEditField_2Label.Text = '{σ^2}_Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sec2gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end