classdef sec3Bonusgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        WEditField_2              matlab.ui.control.NumericEditField
        WEditField_2Label         matlab.ui.control.Label
        COV_WZEditField           matlab.ui.control.NumericEditField
        COV_WZEditFieldLabel      matlab.ui.control.Label
        WZEditField               matlab.ui.control.NumericEditField
        WZEditFieldLabel          matlab.ui.control.Label
        WEditField                matlab.ui.control.NumericEditField
        WEditFieldLabel           matlab.ui.control.Label
        ZEditField_2              matlab.ui.control.NumericEditField
        ZEditField_2Label         matlab.ui.control.Label
        ZEditField                matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        FunctionsofRandomVariablesLabel  matlab.ui.control.Label
        AddbeforeanyofTheseOperatorLabel  matlab.ui.control.Label
        EnterWEditField           matlab.ui.control.EditField
        EnterWEditFieldLabel      matlab.ui.control.Label
        EnterZEditField           matlab.ui.control.EditField
        EnterZEditFieldLabel      matlab.ui.control.Label
        LoadFileandAnalyzeButton  matlab.ui.control.Button
        BackButton                matlab.ui.control.Button
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %X
        %Y
    end
    
    methods (Access = public)
        
        function rvType = RVType(~,RV)
            uniqueValues = unique(RV);
            ratioUnique = length(uniqueValues) / length(RV);
            threshold = 0.05;
            if ratioUnique > threshold
            rvType = 'Continuous';
            else
            rvType = 'Discrete';
            end
            fprintf('Ratio of unique values: %.4f\n', ratioUnique);
            fprintf('Determined RV type: %s\n', rvType);
            end

            function [PDF, x] = ksDen(~,data, bandwidth)
            min_x = min(data) - 3 * bandwidth;
            max_x = max(data) + 3 * bandwidth;
            x = linspace(min_x, max_x, ceil(sqrt(length(data))));
            PDF = zeros(size(x));
            n = length(data);
            for i = 1:length(x)
            for j = 1:n
            PDF(i) = PDF(i) + (1 / sqrt(2 * pi)) * exp(-0.5 * ((x(i) - data(j)).^2) / bandwidth);

            end
            PDF(i) = PDF(i) / (n * bandwidth);
            end
            end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            delete(app.UIFigure);
            sec3gui;
        end

        % Button pushed function: LoadFileandAnalyzeButton
        function LoadFileandAnalyzeButtonPushed(app, event)
    % Open a file dialog to select a .mat file
    [file, path] = uigetfile('*.mat', 'Select a .mat File to Load');
    
    % Check if the user selected a file or canceled the dialog
    if isequal(file, 0)
        % User canceled the file selection
        uialert(app.UIFigure, 'No file selected.', 'File Selection');
    else
        % Construct the full file path
        fullFilePath = fullfile(path, file);
        
        % Display the selected file path (optional, for debugging)
        disp(['Selected file: ', fullFilePath]);
        
        % Load the .mat file
        try
            % Get variable names in the .mat file
            variables = who('-file', fullFilePath);
            
            % Ensure the file contains at least one variable
            if ~isempty(variables)
                % Load the first variable
                matData = load(fullFilePath, variables{1});
                
                % Access the variable dynamically
                data = matData.(variables{1});
                
                % Check if the variable is a 2-row matrix
                if ismatrix(data) && size(data, 1) == 2
                    % Split the rows into RV1 and RV2
                    X = data(1, :);
                    Y = data(2, :);
                    
                    % Display loaded variables (optional, for debugging)
                    disp(['Loaded variable: ', variables{1}]);
                    disp('X:');
                    disp(X);
                    disp('Y:');
                    disp(Y);
                else
                    % Alert if the variable is not a 2-row matrix
                    uialert(app.UIFigure, 'The variable must be a 2-row matrix.', 'Error');
                end
            else
                % Alert if no variables are found
                uialert(app.UIFigure, 'The selected file contains no variables.', 'Error');
            end
        catch ME
            % Handle any errors during file loading
            uialert(app.UIFigure, ['Error loading file: ', ME.message], 'Error');
        end
    end
            
            zString = app.EnterZEditField.Value;
            Z = eval(zString);
            wString = app.EnterWEditField.Value;
            W = eval(wString);

            disp('Both functions are valid.');
            disp('Z evaluates to:');
            disp(Z);
            disp('W evaluates to:');
            disp(W);

 % Determine RV types (improved)
            zType = app.RVType(Z);
            wType = app.RVType(W);

            % Calculate histogram bin edges using Scott's rule
            [~, bins_X] = histcounts(Z, 80,'Normalization', 'probability');
            [~, bins_Y] = histcounts(W, 800,'Normalization', 'probability');

            % Calculate 2D histogram
            [H, xedges, yedges] = histcounts2(Z, W, bins_X, bins_Y, 'Normalization', 'probability');

            %Create meshgrid for plotting (using bin centers)
            xcenters = (xedges(1:end-1) + xedges(2:end)) / 2;
            ycenters = (yedges(1:end-1) + yedges(2:end)) / 2;
            [xpos, ypos] = meshgrid(xcenters, ycenters);

            % Plot using surf
            figure;
            surf(app.UIAxes_4,xpos, ypos, H');  % Use H directly, no need to flatten or filter
            view(3);% Set 3D view

            meanZ=mean(Z);
            meanW=mean(W);
            varZ=var(Z);
            varW=var(W); 

            app.ZEditField.Value = double(meanZ);
            app.WEditField.Value =double(meanW);
            app.ZEditField_2.Value = double(varZ);
            app.WEditField_2.Value = double(varW);

            covarianceMatrix = cov(Z, W); % Covariance matrix
            covWZ = covarianceMatrix(1, 2);      % Covariance between X and Y
            app.COV_WZEditField.Value=covWZ;

            correlationMatrix = corrcoef(Z, W); % Correlation coefficient matrix
            corrWZ = correlationMatrix(1, 2);          % Correlation coefficient between X and Y
            app.WZEditField.Value=corrWZ;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            numBins = ceil(sqrt(length(Z))); % Adjust as needed

if strcmpi(zType, 'Discrete')
[zCounts, zEdges] = histcounts(Z, numBins, 'Normalization', 'probability');
zXValues = (zEdges(1:end-1) + zEdges(2:end)) / 2;
bar(app.UIAxeszXValues, zCounts);
else
zBandwidth = 0.1; % Adjust bandwidth as needed
[zPDF, zx] = app.ksDen(Z, zBandwidth);
plot(app.UIAxes,zx, zPDF);
end

figure;
if strcmpi(wType, 'Discrete')
[wCounts, wEdges] = histcounts(W, numBins, 'Normalization', 'probability');
wXValues = (wEdges(1:end-1) + wEdges(2:end)) / 2;
bar(app.UIAxes_2,wXValues, wCounts);
else
wBandwidth = 0.1;  % Adjust bandwidth as needed
[wPDF, wx] = app.ksDen(W, wBandwidth);
plot(app.UIAxes_2,wx, wPDF);
end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PDF of Z')
            xlabel(app.UIAxes, 'Z')
            ylabel(app.UIAxes, '{f_Z}(z)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'PDF of W')
            xlabel(app.UIAxes_2, 'W')
            ylabel(app.UIAxes_2, '{f_W}(w)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Joint PDF of Z,W')
            xlabel(app.UIAxes_4, 'W')
            ylabel(app.UIAxes_4, '{f_ZW}(z,w)')
            zlabel(app.UIAxes_4, 'Z')
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.LoadFileandAnalyzeButton = uibutton(app.UIFigure, 'push');
            app.LoadFileandAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileandAnalyzeButtonPushed, true);
            app.LoadFileandAnalyzeButton.Text = 'Load File and Analyze';
            app.EnterZEditFieldLabel = uilabel(app.UIFigure);
            app.EnterZEditFieldLabel.Interpreter = 'tex';
            app.EnterZEditFieldLabel.Text = 'Enter Z';
            app.EnterZEditField = uieditfield(app.UIFigure, 'text');
            app.EnterWEditFieldLabel = uilabel(app.UIFigure);
            app.EnterWEditFieldLabel.Interpreter = 'tex';
            app.EnterWEditFieldLabel.Text = 'Enter W';
            app.EnterWEditField = uieditfield(app.UIFigure, 'text');
            app.AddbeforeanyofTheseOperatorLabel = uilabel(app.UIFigure);
            app.AddbeforeanyofTheseOperatorLabel.Text = {'Add (.) before any of '; 'These Operator'; '( * , / , ^ )'};
            app.FunctionsofRandomVariablesLabel = uilabel(app.UIFigure);
            app.FunctionsofRandomVariablesLabel.Text = 'Functions of Random Variables';
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Interpreter = 'tex';
            app.ZEditFieldLabel.Text = 'μ_Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Interpreter = 'tex';
            app.ZEditField_2Label.Text = '{''{σ^2}_Z''}';
            app.ZEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.WEditFieldLabel = uilabel(app.UIFigure);
            app.WEditFieldLabel.Interpreter = 'tex';
            app.WEditFieldLabel.Text = 'μ_W';
            app.WEditField = uieditfield(app.UIFigure, 'numeric');
            app.WZEditFieldLabel = uilabel(app.UIFigure);
            app.WZEditFieldLabel.Interpreter = 'tex';
            app.WZEditFieldLabel.Text = 'ρ_WZ';
            app.WZEditField = uieditfield(app.UIFigure, 'numeric');
            app.COV_WZEditFieldLabel = uilabel(app.UIFigure);
            app.COV_WZEditFieldLabel.Interpreter = 'tex';
            app.COV_WZEditFieldLabel.Text = 'COV_WZ';
            app.COV_WZEditField = uieditfield(app.UIFigure, 'numeric');
            app.WEditField_2Label = uilabel(app.UIFigure);
            app.WEditField_2Label.Interpreter = 'tex';
            app.WEditField_2Label.Text = '{''{σ^2}_W''}';
            app.WEditField_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sec3Bonusgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end